classdef ElectricWheelchairApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectricWheelchairAppUIFigure   matlab.ui.Figure
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        NumericalsimulationButton       matlab.ui.control.ToggleButton
        PhysicssimulationButton         matlab.ui.control.ToggleButton
        ExperimentButton                matlab.ui.control.ToggleButton
        OfflinesimulationButton         matlab.ui.control.ToggleButton
        ElectricWheelchairAppLabel      matlab.ui.control.Label
        EndButton                       matlab.ui.control.Button
        StartButton                     matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        TranslationalvelocityLabel      matlab.ui.control.Label
        TranslationalvelocityGaugeLabel  matlab.ui.control.Gauge
        versionLabel                    matlab.ui.control.Label
        RotationalvelocityLabel         matlab.ui.control.Label
        RotationalvelocityGaugeLabel    matlab.ui.control.Gauge
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SamplingtimeEditFieldLabel      matlab.ui.control.Label
        SamplingtimeEditField           matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel           matlab.ui.control.Label
        EndtimeEditField                matlab.ui.control.NumericEditField
        RosbagpathEditFieldLabel        matlab.ui.control.Label
        RosbagpathEditField             matlab.ui.control.EditField
        MotivehostIPaddressEditFieldLabel  matlab.ui.control.Label
        MotivehostIPaddressEditField    matlab.ui.control.EditField
        ROShostIPaddressEditFieldLabel  matlab.ui.control.Label
        ROShostIPaddressEditField       matlab.ui.control.EditField
        RigitnumberSpinnerLabel         matlab.ui.control.Label
        RigitnumberSpinner              matlab.ui.control.Spinner
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ThetaEditFieldLabel             matlab.ui.control.Label
        ThetaEditField                  matlab.ui.control.NumericEditField
        SensorsButtonGroup              matlab.ui.container.ButtonGroup
        DcameraSwitchLabel              matlab.ui.control.Label
        DcameraSwitch                   matlab.ui.control.Switch
        LiDARSwitchLabel                matlab.ui.control.Label
        LiDARSwitch                     matlab.ui.control.Switch
        RealSenseSwitchLabel            matlab.ui.control.Label
        RealSenseSwitch                 matlab.ui.control.Switch
        LabName                         matlab.ui.control.Label
    end

    properties (Constant, Access = private)
        WaitTime = 1.e-9;
    end
    properties
        SelectedMode
        Sensor
        Addr
        FileName
        RgtNum
        EndTime
        SamplingTime
        State
        StartState
        End
        RbPath
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.StartButton.Value = 0;
            app.StartButton.Text = "Start";
            app.StartButton.BackgroundColor = [1.,1.,1.];
            app.Lamp.Color = [0., 0., 0.];
            app.State = 0;
            if isstruct(varargin)
                app.Sensor = sensor;
                app.SelectedMode = 1;
                app.Addr    = cell(2,1);
                app.Addr{1} = addr.ROSHostIP;
                app.Addr{2} = addr.HostIP;
                app.FileName= 'test';
                app.RgtNum  = rgtNum;
                app.EndTime = Te;
                app.SamplingTime = Ts;
                app.End = 0;
                app.State = 0;
                app.StartState = startState;
                app.RbPath = rbPath;

                app.ModeButtonGroup.SelectedObject.Text = "Numerical simulation";
                app.ROShostIPaddressEditField.Value     = addr.ROSHostIP;
                app.MotivehostIPaddressEditField.Value  = addr.HostIP;
                if isempty(rbPath)
                    app.RosbagpathEditField.Value = pwd;
                else
                    app.RosbagpathEditField.Value = rbPath;
                end
                app.FilenameEditField.Value     = 'test';
                app.RigitnumberSpinner.Value    = rgtNum;
                app.EndtimeEditField.Value      = Te;
                app.SamplingtimeEditField.Value = Ts;
                app.XEditField.Value    = startState(1,1);
                app.YEditField.Value    = startState(2,1);
                app.ThetaEditField.Value= startState(3,1);
                if app.Sensor(1) == true
                    app.DcameraSwitch.Value = 'On';
                elseif app.Sensor(1) == false
                    app.DcameraSwitch.Value = 'Off';
                end
                if app.Sensor(2) == true
                    app.LiDARSwitch.Value = 'On';
                elseif app.Sensor(2) == false
                    app.LiDARSwitch.Value = 'Off';
                end
                if app.Sensor(3) == true
                    app.RealSenseSwitch.Value = 'On';
                elseif app.Sensor(3) == false
                    app.RealSenseSwitch.Value = 'Off';
                end
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1 && app.StartButton.Text == "Start"
                app.StartButton.Value = 0;
                app.StartButton.Text = "Stop";
                app.StartButton.BackgroundColor = [1., 0., 0.];
                app.Lamp.Color = [0., 1., 0.];
                app.State = 1;
            elseif value == 1 && app.StartButton.Text == "Stop"
                app.StartButton.Value = 0;
                app.StartButton.Text = "Start";
                app.StartButton.BackgroundColor = [1.,1.,1.];
                app.Lamp.Color = [0., 0., 0.];
                app.State = 0;
            end
            pause(app.WaitTime);
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            value = app.ModeButtonGroup.SelectedObject;
            switch value.Text
      		case "Numerical simulation"
                    app.SelectedMode = 1;
      		case "Physics simulation"
                    app.SelectedMode = 2;
      		case "Experiment"
                    app.SelectedMode = 3;
                case "Offline"
                    app.SelectedMode = 4;
            end
        end

        % Value changed function: ROShostIPaddressEditField
        function ROShostIPaddressEditFieldValueChanged(app, event)
            value = app.ROShostIPaddressEditField.Value;
            app.Addr{1} = value;
        end

        % Value changed function: MotivehostIPaddressEditField
        function MotivehostIPaddressEditFieldValueChanged(app, event)
            value = app.MotivehostIPaddressEditField.Value;
            app.Addr{2} = value;
        end

        % Value changed function: RosbagpathEditField
        function RosbagpathEditFieldValueChanged(app, event)
            value = app.RosbagPathEditField.Value;
            app.RbPath = value;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            app.FileName = value;
        end

        % Value changed function: RigitnumberSpinner
        function RigitnumberSpinnerValueChanged(app, event)
            value = app.RigitnumberSpinner.Value;
            app.RgtNum = value;
        end

        % Value changed function: EndtimeEditField
        function EndtimeEditFieldValueChanged(app, event)
            value = app.EndtimeEditField.Value;
            app.EndTime = value;
        end

        % Value changed function: SamplingtimeEditField
        function SamplingtimeEditFieldValueChanged(app, event)
            value = app.SamplingtimeEditField.Value;
            app.SamplingTime = value;
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            disp('End of the wheelchair application. See you!');
            delete(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.StartState(1,1) = value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.StartState(2,1) = value;
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            value = app.ThetaEditField.Value;
            app.StartState(3,1) = value;
        end

        % Value changed function: DcameraSwitch
        function cameraSwitchValueChanged(app, event)
            value = app.DcameraSwitch.Value;
            if value == "On"
                app.Sensor(1) = true;
            elseif value == "Off"
                app.Sensor(1) = false;
            end
        end

        % Value changed function: LiDARSwitch
        function LiDARSwitchValueChanged(app, event)
            value = app.LiDARSwitch.Value;
            if value == "On"
                app.Sensor(2) = true;
            elseif value == "Off"
                app.Sensor(2) = false;
            end
        end

        % Value changed function: RealSenseSwitch
        function RealSenseSwitchValueChanged(app, event)
            value = app.RealSenseSwitch.Value;
            if value == "On"
                app.Sensor(3) = true;
            elseif value == "Off"
                app.Sensor(3) = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectricWheelchairAppUIFigure = uifigure('Visible', 'off');
            app.ElectricWheelchairAppUIFigure.Name = 'Electric Wheelchair App';
            app.ModeButtonGroup = uibuttongroup(app.ElectricWheelchairAppUIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.NumericalsimulationButton = uitogglebutton(app.ModeButtonGroup);
            app.NumericalsimulationButton.Text = 'Numerical simulation';
            app.NumericalsimulationButton.Value = true;
            app.PhysicssimulationButton = uitogglebutton(app.ModeButtonGroup);
            app.PhysicssimulationButton.Text = 'Physics simulation';
            app.ExperimentButton = uitogglebutton(app.ModeButtonGroup);
            app.ExperimentButton.Text = 'Experiment';
            app.OfflinesimulationButton = uitogglebutton(app.ModeButtonGroup);
            app.OfflinesimulationButton.Text = 'Offline simulation';
            app.ElectricWheelchairAppLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.ElectricWheelchairAppLabel.Text = 'Electric Wheelchair App';
            app.EndButton = uibutton(app.ElectricWheelchairAppUIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.StartButton = uibutton(app.ElectricWheelchairAppUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.Lamp = uilamp(app.ElectricWheelchairAppUIFigure);
            app.TranslationalvelocityLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.TranslationalvelocityLabel.Text = 'Translational velocity';
            app.TranslationalvelocityGaugeLabel = uigauge(app.ElectricWheelchairAppUIFigure, 'circular');
            app.TranslationalvelocityGaugeLabel.Limits = [0 1];
            app.TranslationalvelocityGaugeLabel.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.TranslationalvelocityGaugeLabel.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.versionLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.versionLabel.Text = 'version 3.7';
            app.RotationalvelocityLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.RotationalvelocityLabel.Text = 'Rotational velocity';
            app.RotationalvelocityGaugeLabel = uigauge(app.ElectricWheelchairAppUIFigure, 'circular');
            app.RotationalvelocityGaugeLabel.Limits = [0 1];
            app.RotationalvelocityGaugeLabel.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.FilenameEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.SamplingtimeEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.SamplingtimeEditFieldLabel.Text = 'Sampling time';
            app.SamplingtimeEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'numeric');
            app.SamplingtimeEditField.Limits = [0 Inf];
            app.SamplingtimeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingtimeEditFieldValueChanged, true);
            app.EndtimeEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'numeric');
            app.EndtimeEditField.Limits = [0 Inf];
            app.EndtimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimeEditFieldValueChanged, true);
            app.RosbagpathEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.RosbagpathEditFieldLabel.Text = 'Rosbag path';
            app.RosbagpathEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'text');
            app.RosbagpathEditField.ValueChangedFcn = createCallbackFcn(app, @RosbagpathEditFieldValueChanged, true);
            app.MotivehostIPaddressEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.MotivehostIPaddressEditFieldLabel.Text = 'Motive host IP address';
            app.MotivehostIPaddressEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'text');
            app.MotivehostIPaddressEditField.ValueChangedFcn = createCallbackFcn(app, @MotivehostIPaddressEditFieldValueChanged, true);
            app.ROShostIPaddressEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.ROShostIPaddressEditFieldLabel.Text = 'ROS host IP address';
            app.ROShostIPaddressEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'text');
            app.ROShostIPaddressEditField.ValueChangedFcn = createCallbackFcn(app, @ROShostIPaddressEditFieldValueChanged, true);
            app.RigitnumberSpinnerLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.RigitnumberSpinnerLabel.Text = 'Rigit number';
            app.RigitnumberSpinner = uispinner(app.ElectricWheelchairAppUIFigure);
            app.RigitnumberSpinner.Limits = [1 32];
            app.RigitnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @RigitnumberSpinnerValueChanged, true);
            app.RigitnumberSpinner.Value = 1;
            app.XEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ThetaEditFieldLabel = uilabel(app.ElectricWheelchairAppUIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.ElectricWheelchairAppUIFigure, 'numeric');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.SensorsButtonGroup = uibuttongroup(app.ElectricWheelchairAppUIFigure);
            app.SensorsButtonGroup.Title = 'Sensors';
            app.DcameraSwitchLabel = uilabel(app.SensorsButtonGroup);
            app.DcameraSwitchLabel.Text = '3D camera';
            app.DcameraSwitch = uiswitch(app.SensorsButtonGroup, 'slider');
            app.DcameraSwitch.ValueChangedFcn = createCallbackFcn(app, @cameraSwitchValueChanged, true);
            app.LiDARSwitchLabel = uilabel(app.SensorsButtonGroup);
            app.LiDARSwitchLabel.Text = 'LiDAR';
            app.LiDARSwitch = uiswitch(app.SensorsButtonGroup, 'slider');
            app.LiDARSwitch.ValueChangedFcn = createCallbackFcn(app, @LiDARSwitchValueChanged, true);
            app.RealSenseSwitchLabel = uilabel(app.SensorsButtonGroup);
            app.RealSenseSwitchLabel.Text = 'RealSense';
            app.RealSenseSwitch = uiswitch(app.SensorsButtonGroup, 'slider');
            app.RealSenseSwitch.ValueChangedFcn = createCallbackFcn(app, @RealSenseSwitchValueChanged, true);
            app.LabName = uilabel(app.ElectricWheelchairAppUIFigure);
            app.LabName.Text = 'Adbanced Control Systems Lab.';
            % Show the figure after all components are created
            app.ElectricWheelchairAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectricWheelchairApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectricWheelchairAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectricWheelchairAppUIFigure)
        end
    end
end